classdef Stained_GLASS_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CellAnnotationFileVariablesTab  matlab.ui.container.Tab
        RegexResultEditField            matlab.ui.control.EditField
        ResultEditFieldLabel            matlab.ui.control.Label
        VariablePreviewDropDown         matlab.ui.control.DropDown
        PreviewfileDropDown_2Label      matlab.ui.control.Label
        PositionUnitDropDown            matlab.ui.control.DropDown
        PositionunitDropDownLabel       matlab.ui.control.Label
        YPositionDropDown               matlab.ui.control.DropDown
        YpositionDropDownLabel          matlab.ui.control.Label
        XPositionDropDown               matlab.ui.control.DropDown
        XpositionDropDownLabel          matlab.ui.control.Label
        StainIntensityDropDown          matlab.ui.control.DropDown
        StainIntensityDropDownLabel     matlab.ui.control.Label
        StainClassDropDown              matlab.ui.control.DropDown
        StainclassDropDownLabel         matlab.ui.control.Label
        ImageNameRegexEditField         matlab.ui.control.EditField
        ImagenameextractorregexLabel    matlab.ui.control.Label
        ImageNameDropDown               matlab.ui.control.DropDown
        ImagenameDropDownLabel          matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        RefreshplotButton               matlab.ui.control.Button
        ClassnamesTextArea              matlab.ui.control.TextArea
        ClassnamesTextAreaLabel         matlab.ui.control.Label
        StainthresholdsEditField        matlab.ui.control.EditField
        StainthresholdsEditFieldLabel   matlab.ui.control.Label
        PreviewfileDropDown             matlab.ui.control.DropDown
        PreviewfileDropDownLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        VersionLabel                    matlab.ui.control.Label
        FilePairingLabel                matlab.ui.control.Label
        GLASSAILogFileLabel             matlab.ui.control.TextArea
        SelectGLASSAILogFileButton      matlab.ui.control.Button
        WholeImageSummaryLabel          matlab.ui.control.TextArea
        SelectWholeImageSummaryButton   matlab.ui.control.Button
        GLASSAIAnnotationFileTable      matlab.ui.control.Table
        ManualAnnotationFileTable       matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        SelectGLASSAIAnnotationButton   matlab.ui.control.Button
        SelectManualAnnotationButton    matlab.ui.control.Button
    end


    properties (Access = private)
        GLASS_AI_MANUAL_EDITOR_VERSION = "1.0"
        selectedGLASSAIFileList % store list of selected GLASS-AI classification files
        GLASSAIAnnotationFilePath % path to directory containing selected GLASS-AI annotation files
        selectedCellAnnotationFileList % store list of selected manual annotation files
        CellAnnotationFilePath % path to directory containing selected manual annotation files
        WholeImageInfoFilePath % Path to whole image summary
        WholeImageInfoFileName = "Whole Slide Summary_adjusted.xlsx" % File name for whole image summary
        GLASSAILogFileName % GLASS-AI log for analysis of selected GLASS-AI annotation files
        GLASSAILogFilePath % Path to GLASS-AI log file
        LASTDIR % store last directory selected for quicker navigation
        ASSIGN_OVERALL_GRADES = true % default to assigning overall tumor grades
        ASSIGN_GRADES_THRESHOLD = 0.1 %default area proportion threshold for overall tumor grading
        ASSIGN_GRADES_METHOD = "highest" %default method for assigning overall tumor grades
        TUMOR_SIZE_THRESHOLD = 20 % Store size threshold for individual tumor segmentation
        TUMOR_MERGE_RADIUS = ceil(5/0.5022); % convert microns to pixels% Store value for smoothing radius in microns
        validFilePairings % List of paired files
        currentFileName % Name of file currently being processed
    end

    methods (Access = private)

        function main(app)
            clc;

            for iFile = 1:length(app.validFilePairings)
                app.currentFileName = app.validFilePairings{iFile};
                classifications_adjusted_outfile = fullfile(app.GLASSAIAnnotationFilePath,sprintf("%s_classes_adjusted.mat",app.currentFileName));

                % adjust classifications using manual annotations
                classifications_adjusted = generateannotationsfromQuPath(app);
                sprintf("%s - %s %s\n", string(datetime),"Adjusted annotations from QuPath geojson for",app.currentFileName)
                classifications = classifications_adjusted;
                save(classifications_adjusted_outfile,'classifications','-v7.3');
                clear classifications
                sprintf("%s - %s %s\n", string(datetime),"Saved adjusted classifications for",app.currentFileName)

                %create new tumor grade map
                grade_map = createtumorgradeimage(app,classifications_adjusted);
                sprintf("%s - %s %s\n", string(datetime),"Made adjusted tumor grade map for",app.currentFileName)
                grade_map = imresize(grade_map,0.25,'nearest');
                %save new tumor grade map to file
                if (size(grade_map,1) * size(grade_map,2)) > 40000000 % use writetiff() for large images
                    writetiff(app,grade_map,strcat(app.currentFileName,"_adjusted_grades.tif"));
                else %use imwrite for smaller images
                    imwrite(grade_map,fullfile(app.GLASSAIAnnotationFilePath,strcat(app.currentFileName,"_adjusted_grades.tif")),'tif','Compression','lzw');
                end % End IF: use writetiff() for large images
                clear grade_map
                sprintf("%s - %s %s\n", string(datetime),"Saved adjusted tumor grade map for",app.currentFileName)

                %create new overall tumor grade map
                segmentedTumorMask = segmentindividualtumors(app,classifications_adjusted);
                sprintf("%s - %s %s\n", string(datetime),"Segmented individual tumors for",app.currentFileName)

                individualTumorStats = individualtumoranalysis(app,classifications_adjusted,segmentedTumorMask);
                sprintf("%s - %s %s\n", string(datetime),"Analyzed individual tumors for",app.currentFileName)

                individualTumorStatsFileName = strcat(app.currentFileName, "_adjusted.xlsx");
                writetable(individualTumorStats,fullfile(app.GLASSAIAnnotationFilePath,individualTumorStatsFileName),'WriteMode','overwrite');
                sprintf("%s - %s %s %s %s\n", string(datetime),"Saved individual tumor stats for",app.currentFileName,"to",individualTumorStatsFileName)

                wholeImageStats = imageleveltumoranalysis(app,classifications_adjusted,individualTumorStats);
                writetable(wholeImageStats,fullfile(app.WholeImageInfoFilePath,app.WholeImageInfoFileName),'WriteMode','append');
                sprintf("%s - %s %s %s %s\n", string(datetime),"Appended image-level tumor stats for",app.currentFileName,"to",app.WholeImageInfoFileName)

                tumorIDMask = maketumorlabelmask(app,segmentedTumorMask);
                clear segmentedTumorMask
                overallTumorGradeMask = makeoveralltumorgrademask(app,classifications_adjusted,tumorIDMask,individualTumorStats);
                sprintf("%s - %s %s\n", string(datetime),"Made adjusted overall tumor grade classifications for",app.currentFileName)

                %save new overall tumor grade mask to file
                overallTumorGradeOutputFileName=fullfile(app.GLASSAIAnnotationFilePath, strcat(app.currentFileName,'_adjusted_overall_tumor_grades.mat'));
                save(overallTumorGradeOutputFileName,'overallTumorGradeMask', '-v7.3');
                sprintf("%s - %s %s\n", string(datetime),"Saved adjusted overall tumor grade classifications for",app.currentFileName)

                %make new overall tumor grade map
                overallTumorGradeImage = createtumorgradeimage(app,overallTumorGradeMask);
                sprintf("%s - %s %s\n", string(datetime),"Made adjusted overall tumor grade map for",app.currentFileName)
                overallTumorGradeImage = imresize(overallTumorGradeImage,0.25,'nearest');
                clear overallTumorGradeMask; % clear overallTumorGradeMask matrix from memory
                overallTumorGradeImageOutputFileName = fullfile(app.GLASSAIAnnotationFilePath,strcat(app.currentFileName+"_adjusted_overall_grades.tif"));
                if (size(overallTumorGradeImage,1) * size(overallTumorGradeImage,2)) > 40000000 % use writetiff() for large images
                    writetiff(app,overallTumorGradeImage,strcat(app.currentFileName,"_adjusted_overall_grades.tif"));
                else %use imwrite for smaller images
                    imwrite(overallTumorGradeImage,overallTumorGradeImageOutputFileName,'tif','Compression','lzw');
                end % End IF: use writetiff() for large images
                sprintf("%s - %s %s\n", string(datetime),"Saved adjusted overall tumor grade map for",app.currentFileName)
            end %end FOR: loop over each paired file

            uialert(app.UIFigure,"Processing complete","DONE",'Icon',"success")
        end

        function segmentedTumorMask = segmentindividualtumors(app, classifications)
            % create a binary image mask of tumors after filling holes,
            % merging nearby tumor pixels, and filtering out small tumors

            PIXEL_AREA = 0.5022^2;

            % make a mask of tumor pixels
            tumorMask = classifications==1|classifications==2|classifications==3|classifications==4|classifications==8;
            clear classifications %free up some memory

            % Fill holes in tumors and merge tumors within app.TUMOR_MERGE_RADIUS
            tumorMask = imfill(tumorMask,'holes'); % fill exisiting holes
            tumorMask = imclose(tumorMask,strel('disk',ceil(app.TUMOR_MERGE_RADIUS/PIXEL_AREA)));
            tumorMask = imfill(tumorMask,'holes'); % fill new holes

            % Filter tumors smaller than app.TUMOR_SIZE_THRESHOLD
            segmentedTumorMask = bwareaopen(tumorMask,ceil(app.TUMOR_SIZE_THRESHOLD/PIXEL_AREA),8);

        end % End function: segmentindividualtumors

        function individualTumorStats = individualtumoranalysis(app,classifications,segmentedTumorMask)
            % Calculates stats for individual tumors in image

            PIXEL_AREA = 0.5022^2;

            try
                % get indices for pixels of each grade
                indicesG1 = find(classifications==1);
                indicesG2 = find(classifications==2);
                indicesG3 = find(classifications==3);
                indicesG4 = find(classifications==4);
                indicesG5 = find(classifications==8);
                clear classifications %free up some memory

                % get tumor centroids for labeling
                tumorCC = bwconncomp(segmentedTumorMask,8); % Segment individual tumors
                s = regionprops(tumorCC,'Centroid'); % Get centroid coordinates for tumor labels
                tumorCentroids = cat(1,s.Centroid); % Convert centroid coordinates to [x y] matrix
                clear segmentedTumorMask

                % individual tumor table
                %- define column names and data types
                individualTumorTableCols = { %15 variables
                    %column name            %data type
                    'image_id'              'string'
                    'tumor_id'              'double'
                    'tumor_grade'           'double'
                    'grade_assign_method'   'string'
                    'g1_percentage'         'double'
                    'g2_percentage'         'double'
                    'g3_percentage'         'double'
                    'g4_percentage'         'double'
                    'g5_percentage'         'double'
                    'g1_area'               'double'
                    'g2_area'               'double'
                    'g3_area'               'double'
                    'g4_area'               'double'
                    'g5_area'               'double'
                    'total_tumor_area'      'double'
                    'centroid_x'            'double'
                    'centroid_y'            'double'
                    }'; %transpose to horizontal cell array before assignment
                %- initialize table
                individualTumorTableColsCount = size(individualTumorTableCols,2);

                % analyze individual tumors
                %- skip if no tumors were found in slide
                if ~isempty(tumorCentroids)

                    % Get segmented tumor count
                    totalTumorCountInSlide = length(tumorCentroids(:,1));

                    % intialize individual tumors table for slide
                    individualTumorStats = table( ...
                        'Size',[totalTumorCountInSlide individualTumorTableColsCount],...
                        'VariableNames',individualTumorTableCols(1,:),...
                        'VariableTypes',individualTumorTableCols(2,:));

                    % analyze each segmented tumor in image
                    for iTumor = 1:totalTumorCountInSlide
                        % get indices of pixels in tumor
                        tumorIndices=tumorCC.PixelIdxList{iTumor};

                        % Calculate area of each grade in individual tumor
                        tumorG1Area = nnz(ismember(tumorIndices,indicesG1))*PIXEL_AREA;
                        tumorG2Area = nnz(ismember(tumorIndices,indicesG2))*PIXEL_AREA;
                        tumorG3Area = nnz(ismember(tumorIndices,indicesG3))*PIXEL_AREA;
                        tumorG4Area = nnz(ismember(tumorIndices,indicesG4))*PIXEL_AREA;
                        tumorG5Area = nnz(ismember(tumorIndices,indicesG5))*PIXEL_AREA;

                        % Sum area of all grades in individual tumor
                        tumorTotalArea = tumorG1Area + tumorG2Area + tumorG3Area + tumorG4Area + tumorG5Area;

                        % Calculate proportion of each grade in tumor
                        tumorG1Percent = (double(tumorG1Area)/tumorTotalArea);
                        tumorG2Percent = (double(tumorG2Area)/tumorTotalArea);
                        tumorG3Percent = (double(tumorG3Area)/tumorTotalArea);
                        tumorG4Percent = (double(tumorG4Area)/tumorTotalArea);
                        tumorG5Percent = (double(tumorG5Area)/tumorTotalArea);

                        % Assign overall grade to tumor
                        if app.ASSIGN_OVERALL_GRADES
                            switch app.ASSIGN_GRADES_METHOD
                                case "majority"
                                    [~,tumorGrade] = max([tumorG1Percent tumorG2Percent tumorG3Percent tumorG4Percent tumorG5Percent]);
                                case "highest"
                                    if tumorG5Percent >= app.ASSIGN_GRADES_THRESHOLD
                                        tumorGrade = 5;
                                    elseif tumorG4Percent >= app.ASSIGN_GRADES_THRESHOLD
                                        tumorGrade = 4;
                                    elseif tumorG3Percent >= app.ASSIGN_GRADES_THRESHOLD
                                        tumorGrade = 3;
                                    elseif tumorG2Percent >= app.ASSIGN_GRADES_THRESHOLD
                                        tumorGrade = 2;
                                    elseif tumorG1Percent >= app.ASSIGN_GRADES_THRESHOLD
                                        tumorGrade = 1;
                                    else
                                        tumorGrade = 0;
                                    end
                                otherwise
                                    tumorGrade = 0;
                            end % End SWITCH: app.ASSIGN_GRADES_METHOD
                        end


                        % add individual tumor stats to table
                        individualTumorStats(iTumor,:) = {
                            %variable                   %column name            %data type
                            sprintf("%s%s",app.currentFileName,"_adjusted"),...     'image_id'              'string'
                            iTumor,...                  'tumor_id'              'double'
                            tumorGrade,...              'tumor_grade'           'double'
                            app.ASSIGN_GRADES_METHOD,...'grade_assign_method'   'string'
                            tumorG1Percent,...          'g1_percentage'         'double'
                            tumorG2Percent,...          'g2_percentage'         'double'
                            tumorG3Percent,...          'g3_percentage'         'double'
                            tumorG4Percent,...          'g4_percentage'         'double'
                            tumorG5Percent,...          'g5_percentage'         'double'
                            tumorG1Area,...             'g1_area'               'double'
                            tumorG2Area,...             'g2_area'               'double'
                            tumorG3Area,...             'g3_area'               'double'
                            tumorG4Area,...             'g4_area'               'double'
                            tumorG5Area,...             'g5_area'               'double'
                            tumorTotalArea,...          'total_tumor_area'      'double'
                            tumorCentroids(iTumor,1),...'centroid_x'            'double'
                            tumorCentroids(iTumor,2),...'centroid_y'            'double'
                            };
                    end %end FOR: individual tumors in slide

                else %no tumors found in slide
                    % output empty individual tumors table
                    individualTumorStats = table( ...
                        'Size',[0 individualTumorTableColsCount],...
                        'VariableNames',individualTumorTableCols(1,:),...
                        'VariableTypes',individualTumorTableCols(2,:));
                end % End IF: ~isempty(tumorCentroids)
            catch ME
                %rethrow error to stop execution
                rethrow(ME);
            end
        end % End function: individualtumoranalysis

        function tumorIdLabelMask = maketumorlabelmask(~, segmentedTumorMask)
            % Make a mask of each segmented tumor that stores the ID of the
            % tumor as the pixel intensity.

            % initialize labeled tumor image
            %- use uint16 data type to fit ≥ 256 individual tumors
            tumorIdLabelMask = zeros(size(segmentedTumorMask),"uint16");

            % get tumor centroids for labeling
            tumorCC = bwconncomp(segmentedTumorMask,8); % Segment individual tumors
            tumorRegionProps = regionprops(tumorCC,'Centroid'); % Get centroid coordinates for tumor labels
            tumorCentroids = cat(1,tumorRegionProps.Centroid); % Convert centroid coordinates to [x y] matrix

            hasTumors = ~isempty(tumorCentroids);
            if hasTumors
                % Get segmented tumor count
                totalTumorCountInSlide = length(tumorCentroids(:,1));

                % analyze each segmented tumor in image
                for iTumor=1:totalTumorCountInSlide
                    % get indices of pixels in tumor
                    classificationIndices=tumorCC.PixelIdxList{iTumor};

                    % add tumor to labeled tumor mask
                    %- use tumor ID as pixel intensity
                    tumorIdLabelMask(classificationIndices) = iTumor;
                end % end FOR: analyze each segemented tumor in image
            else % no tumors found in slide
                return
            end % end IF: hasTumors
        end % End function: maketumorlabelmask

        function overallTumorGradeMask = makeoveralltumorgrademask(~,classifications,tumorIdLabelMask,individualTumorStats)
            % mask individual tumors with overall grade as pixel intensity
            % copy original pixel values to keep normal/background
            overallTumorGradeMask = classifications;
            assignin("base","overallTumorGradeMask_start",overallTumorGradeMask)
            assignin("base","tumorIdLabelMask",tumorIdLabelMask)
            assignin("base","individualTumorStats",individualTumorStats)
            % iterate over segmented tumors
            for iTumor = 1:height(individualTumorStats)
                % assign pixel value based on overall grade
                %- overall grade assigning is done in analyzeindividualtumors()
                %-- method for assigning grade is decided by user

                if individualTumorStats.tumor_grade(iTumor) == 5
                    overallTumorGradeMask(tumorIdLabelMask==iTumor) = 8;
                else
                    overallTumorGradeMask(tumorIdLabelMask==iTumor) = individualTumorStats.tumor_grade(iTumor);
                end
            end
            assignin("base","overallTumorGradeMask_end",overallTumorGradeMask)
        end % end function: makeoveralltumorgrademask

        function annotation_mask = generateannotationsfromQuPath(app)
            % read in annotation file
            annotations_json=jsondecode(fileread(fullfile(app.CellAnnotationFilePath,strcat(app.currentFileName,'.geojson'))));

            % get the number of annotations in annotation file
            annotation_object_count = numel(annotations_json.features);

            %inititalize storage variables
            grades = string(zeros(1,annotation_object_count)); %store annotation name
            annotation_polys = cell(annotation_object_count,1); %store annotation mask

            % iterate over each annotation in annotation file
            for annotation_object = 1:annotation_object_count
                %get annotation name
                grades(annotation_object) = convertCharsToStrings(annotations_json.features(annotation_object).properties.classification.name);
                % get vertices for annotation
                xVerts = annotations_json.features(annotation_object).geometry.coordinates(:,:,1);
                yVerts = annotations_json.features(annotation_object).geometry.coordinates(:,:,2);

                XY = {[xVerts(:), yVerts(:)]};
                annotation_polys(annotation_object) = XY;
            end

            %get polys for tumors of each grade
            G1_annotation_polys = annotation_polys(strcmp(grades, "Grade 1 LUAD"));
            G2_annotation_polys = annotation_polys(strcmp(grades, "Grade 2 LUAD"));
            G3_annotation_polys = annotation_polys(strcmp(grades, "Grade 3 LUAD"));
            G4_annotation_polys = annotation_polys(strcmp(grades, "Grade 4 LUAD"));
            G5_annotation_polys = annotation_polys(strcmp(grades, "Grade 5 LUAD"));
            NormAlv_annotation_polys = annotation_polys(strcmp(grades, "Normal Alveoli"));
            NormAir_annotation_polys = annotation_polys(strcmp(grades, "Normal Airway"));
            ignore_annotation_polys = annotation_polys(strcmp(grades,"Ignore*"));

            %load GLASS-AI annotations
            tmpC  = struct2cell(load(fullfile(app.GLASSAIAnnotationFilePath,strcat(app.currentFileName,'_classes.mat'))));
            annotation_mask = tmpC{1};
            clear tmpC
            background_mask = annotation_mask == 7;

            %make masks from polys
            NormAlv_mask = mbigpoly2mask(app,NormAlv_annotation_polys,size(annotation_mask));
            annotation_mask(NormAlv_mask) = 5;
            clear NormAlv_mask

            NormAir_mask = mbigpoly2mask(app,NormAir_annotation_polys,size(annotation_mask));
            annotation_mask(NormAir_mask) = 6;
            clear NormAir_mask

            G1_mask = mbigpoly2mask(app,G1_annotation_polys,size(annotation_mask));
            annotation_mask(G1_mask) = 1;
            clear G1_mask
            G2_mask = mbigpoly2mask(app,G2_annotation_polys,size(annotation_mask));
            annotation_mask(G2_mask) = 2;
            clear G2_mask
            G3_mask = mbigpoly2mask(app,G3_annotation_polys,size(annotation_mask));
            annotation_mask(G3_mask) = 3;
            clear G3_mask
            G4_mask = mbigpoly2mask(app,G4_annotation_polys,size(annotation_mask));
            annotation_mask(G4_mask) = 4;
            clear G4_mask
            G5_mask = mbigpoly2mask(app,G5_annotation_polys,size(annotation_mask));
            annotation_mask(G5_mask) = 8;
            clear G5_mask

            ignore_mask = mbigpoly2mask(app,ignore_annotation_polys,size(annotation_mask));
            annotation_mask(ignore_mask) = 0;
            clear ignore_mask

            %fill background annotations back in
            annotation_mask(background_mask) = 7;
            clear background_mask
        end

        function gradeImage = createtumorgradeimage(~, classifications)
            % make tumor grade image from classifications
            %initialize gradeImage RGB arrays
            [classificationsWidth,classificationsHeight] = size(classifications);
            ch1 = zeros(classificationsWidth,classificationsHeight, 'uint8');
            ch2 = zeros(classificationsWidth,classificationsHeight, 'uint8');
            ch3 = zeros(classificationsWidth,classificationsHeight, 'uint8');

            % Ignored ROIS, black
            %- can skip since initialized as 0
            %Inds = classifications==0;
            %ch1(Inds) = 0;
            %ch2(Inds) = 0;
            %ch3(Inds) = 0;
            %

            % Grade 1, green
            Inds = classifications==1;
            ch1(Inds) = 0;
            ch2(Inds) = 255;
            ch3(Inds) = 0;

            %Grade 2, blue
            Inds = classifications==2;
            ch1(Inds) = 0;
            ch2(Inds) = 0;
            ch3(Inds) = 255;

            % Grade 3, yellow
            Inds = classifications==3;
            ch1(Inds) = 255;
            ch2(Inds) = 255;
            ch3(Inds) = 0;

            % Grade 4, red
            Inds = classifications==4;
            ch1(Inds) = 255;
            ch2(Inds) = 0;
            ch3(Inds) = 0;

            % Grade 5, light purple
            Inds = classifications==8;
            ch1(Inds) = 150;
            ch2(Inds) = 110;
            ch3(Inds) = 210;

            % Alveoli, cyan
            Inds = classifications==5;
            ch1(Inds) = 0;
            ch2(Inds) = 255;
            ch3(Inds) = 255;

            % Airways, magenta
            Inds = classifications==6;
            ch1(Inds) = 255;
            ch2(Inds) = 0;
            ch3(Inds) = 255;

            % Background/void, white
            Inds = classifications==7;
            ch1(Inds) = 255;
            ch2(Inds) = 255;
            ch3(Inds) = 255;

            %merge channels to create RGB image
            gradeImage = cat(3,ch1,ch2,ch3);
            %convert to uint8 to reduce size
            gradeImage = uint8(gradeImage);

        end %end function: createtumorgradeimage

        function  writetiff(app,Image,filename)
            %write tiff files with metadata

            bt = Tiff(fullfile(app.GLASSAIAnnotationFilePath, filename),'w8');
            tags.ImageLength         = size(Image,1);
            tags.ImageWidth          = size(Image,2);
            tags.Photometric         = Tiff.Photometric.RGB;
            tags.BitsPerSample       = 8;
            tags.SamplesPerPixel     = size(Image,3);
            tags.TileWidth           = 128;
            tags.TileLength          = 128;
            tags.Compression         = Tiff.Compression.JPEG;
            tags.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            tags.Software            = 'MATLAB/GLASS-AI';

            setTag(bt, tags);
            write(bt, Image);
            close(bt);
        end


        function pairedFileNames = validatefilepairs(app)

            % get file names without extensions
            [~,GLASSAIAnnotationFileNames,~] = cellfun(@fileparts, app.selectedGLASSAIFileList,'UniformOutput', false);
            [~,ManualAnnotationFileNames,~] = cellfun(@fileparts, app.selectedCellAnnotationFileList,'UniformOutput', false);
            % clean GLASS-AI annotation file names.
            % manual annotations presumed to be just 'imageID.geojson' which
            % will be clean after `fileparts()`
            wrapperFunc = @(x) extractBefore(x,'_class');
            GLASSAIAnnotationFileNames = cellfun(wrapperFunc, GLASSAIAnnotationFileNames, 'UniformOutput', false);
            clear wrapperFunc

            % find file names present in both lists
            pairedFileNames = intersect(GLASSAIAnnotationFileNames, ManualAnnotationFileNames);
        end

        function imageTumorStatsTable = imageleveltumoranalysis(app, classifications, individualTumorStats)
            % Calculates summary of tumor stats across the image

            PIXEL_AREA = 0.5022^2;

            % change skipped pixels to background
            classifications(classifications==0) = 7;
            % calculate total tissue area in slide
            %- should have no pixels == 0. All background pixels == 7.
            imageTotalTissueArea = (nnz(classifications ~= 7)) * PIXEL_AREA;

            % calculate tumor counts
            imageTotalTumorCount = height(individualTumorStats);

            % calculate the number of tumors of each grade if requested
            %- grade assigment method defined by user
            if app.ASSIGN_OVERALL_GRADES == true
                imageG1TumorCount = nnz(individualTumorStats.tumor_grade==1);
                imageG2TumorCount = nnz(individualTumorStats.tumor_grade==2);
                imageG3TumorCount = nnz(individualTumorStats.tumor_grade==3);
                imageG4TumorCount = nnz(individualTumorStats.tumor_grade==4);
                imageG5TumorCount = nnz(individualTumorStats.tumor_grade==5);
            else
                imageG1TumorCount = NaN;
                imageG2TumorCount = NaN;
                imageG3TumorCount = NaN;
                imageG4TumorCount = NaN;
                imageG5TumorCount = NaN;
            end

            % calculate total area of each grade in the image
            imageG1Area = nnz(classifications==1)*PIXEL_AREA;
            imageG2Area = nnz(classifications==2)*PIXEL_AREA;
            imageG3Area = nnz(classifications==3)*PIXEL_AREA;
            imageG4Area = nnz(classifications==4)*PIXEL_AREA;
            imageG5Area = nnz(classifications==8)*PIXEL_AREA;

            % calculate total tumor area in slide
            imageTotalTumorArea = imageG1Area+imageG2Area+imageG3Area+imageG4Area+imageG5Area;

            % Define tables to save stats
            % Whole slide summary table
            %- define column names and data types
            imageTumorStatsTableCols = { %17 variables
                %column name                    data type
                'image_id'                      'string'
                'number_of_tumors'              'double'
                'g1_tumor_count'                'double'
                'g2_tumor_count'                'double'
                'g3_tumor_count'                'double'
                'g4_tumor_count'                'double'
                'g5_tumor_count'                'double'
                'grade_assign_method'           'string'
                'g1_percentage_in_image'        'double'
                'g2_percentage_in_image'        'double'
                'g3_percentage_in_image'        'double'
                'g4_percentage_in_image'        'double'
                'g5_percentage_in_image'        'double'
                'g1_area_in_image'              'double'
                'g2_area_in_image'              'double'
                'g3_area_in_image'              'double'
                'g4_area_in_image'              'double'
                'g5_area_in_image'              'double'
                'total_tumor_area_in_image'     'double'
                'total_tissue_area_in_image'    'double'
                }';%transpose to horizontal cell array before assignment

            %- intialize table
            imageTumorStatsTable = table( ...
                'Size',[1 size(imageTumorStatsTableCols,2)],...
                'VariableNames',imageTumorStatsTableCols(1,:), ...
                'VariableTypes',imageTumorStatsTableCols(2,:));


            % save whole slide stats
            imageTumorStatsTable(1,:) = {
                %variable                               column name                 data type
                sprintf("%s%s",app.currentFileName,"_adjusted"),... 'image_id'                  'string'
                imageTotalTumorCount,...              'number_of_tumors'          'double'
                imageG1TumorCount,...                   'g1_tumor_count'            'double'
                imageG2TumorCount,...                   'g2_tumor_count'            'double'
                imageG3TumorCount,...                   'g3_tumor_count'            'double'
                imageG4TumorCount,...                   'g4_tumor_count'            'double'
                imageG5TumorCount,...                   'g5_tumor_count'            'double'
                app.ASSIGN_GRADES_METHOD,...            'grade_assign_method'       'string'
                (imageG1Area / imageTotalTumorArea),... 'g1_percentage_in_image'    'double'
                (imageG2Area / imageTotalTumorArea),... 'g2_percentage_in_image'    'double'
                (imageG3Area / imageTotalTumorArea),... 'g3_percentage_in_image'    'double'
                (imageG4Area / imageTotalTumorArea),... 'g4_percentage_in_image'    'double'
                (imageG5Area / imageTotalTumorArea),... 'g5_percentage_in_image'    'double'
                imageG1Area,...                         'g1_area_in_image'          'double'
                imageG2Area,...                         'g2_area_in_image'          'double'
                imageG3Area,...                         'g3_area_in_image'          'double'
                imageG4Area,...                         'g4_area_in_image'          'double'
                imageG5Area,...                         'g5_area_in_image'          'double'
                imageTotalTumorArea,...                 'total_tumor_area_in_image' 'double'
                imageTotalTissueArea,...                'total_tissue_area_in_image''double'
                };
        end % End function: imageleveltumoranalysis


        function readGLASSAILogFile(app)
            logFile = readlines(fullfile(app.GLASSAILogFilePath,app.GLASSAILogFileName));

            getparameterfromlogfile = @(x) extractAfter(logFile(contains(logFile,x)),char(9));

            app.TUMOR_SIZE_THRESHOLD = getparameterfromlogfile('Tumor size threshold');
            app.TUMOR_MERGE_RADIUS = getparameterfromlogfile('Tumor merge distance');
            app.ASSIGN_OVERALL_GRADES = getparameterfromlogfile('Assign overall grades to each tumor');
            app.ASSIGN_GRADES_METHOD = getparameterfromlogfile('Overall tumor grade assignment method');
            app.ASSIGN_GRADES_THRESHOLD= getparameterfromlogfile('Overall tumor grade assignment threshold');
        end

        function outMask = bigpoly2mask(~,xi, yi, m, n)
            % create binary mask from polygons and avoid maximum coordinate
            % limitation from just using poly2mask

            %intialize mask
            outMask = zeros(m,n,'logical');

            % convert vertices to 1-based indexing
            xi = xi + 1;
            yi = yi + 1;

            % make sure min and max don't exceed bounds
            xi(xi > n) = n;
            yi(yi > m) = m;

            %find poly limits and spans
            minX = double(int32(min(xi)));
            maxX = double(int32(max(xi)));
            deltaX = (maxX - minX) + 1; %same as length(minX:maxX)

            assignin("base","minX",minX)
            assignin("base","maxX",maxX)
            assignin("base","deltaX",deltaX)

            minY = double(int32(min(yi)));
            maxY = double(int32(max(yi)));
            deltaY = (maxY - minY) + 1; %same as length(minY:maxY)

            assignin("base","minY",minY)
            assignin("base","maxY",maxY)
            assignin("base","deltaY",deltaY)

            %adjust input coordinate vectors to start at 1,1
            adjXi = xi - minX;
            adjYi = yi - minY;

            assignin("base","adjXi",adjXi)
            assignin("base","adjYi",adjYi)

            %make polymask shifted to 1,1
            smallPoly = poly2mask(adjXi, adjYi, deltaY, deltaX);

            %shift polymask back to original coordinates
            outMask(minY:maxY,minX:maxX) = smallPoly;
        end

        function outMask = mbigpoly2mask(app, XY, BWSIZE)
            % make a mask from multiple polygons stored as separate cells while
            % avoiding maximum coordinate limitations from mpoly2mask (due to
            % underlying usage of poly2mask)

            %initialize output mask
            m = BWSIZE(1);
            n = BWSIZE(2);
            outMask = zeros(m,n,'logical');

            %make mask from each cell of polygon coordinates and union
            for polyEntry = 1:numel(XY)
                xVec = XY{polyEntry}(:,1);
                yVec = XY{polyEntry}(:,2);
                outMask = outMask | bigpoly2mask(app,xVec,yVec,m,n);
            end

        end
        
        function updateCellAnnotationVariableNames(app,inputVariableNames)
            cellAnnotationVariablesReset = [];
            if ~isempty(app.ImageNameDropDown.Value)
                currentValue = app.ImageNameDropDown.Value;
                %reset if current variable not in
                %new list of variable names
                if sum(contains(inputVariableNames,app.ImageNameDropDown.Value)) == 0
                    cellAnnotationVariablesReset = [ cellAnnotationVariablesReset 'Image Name'];
                    app.ImageNameDropDown.Value = '';
                end
            end
            app.ImageNameDropDown.Values = inputVariableNames;
            app.


            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.LASTDIR = pwd;

            app.VersionLabel.Text = strcat("GLASS-AI manual annotation editor v",app.GLASS_AI_MANUAL_EDITOR_VERSION);
            %initilialize file lists to empty cell arrays
            app.selectedGLASSAIFileList = cell(0);
            app.selectedCellAnnotationFileList = cell(0);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %set summary output to GLASS-AI annotation directory if not set
            if isempty(app.WholeImageInfoFilePath)
                app.WholeImageInfoFilePath = app.GLASSAIAnnotationFilePath;
            end

            main(app)

        end

        % Button pushed function: SelectGLASSAIAnnotationButton
        function SelectGLASSAIAnnotationButtonPushed(app, event)
            [app.selectedGLASSAIFileList, app.GLASSAIAnnotationFilePath] = uigetfile({'*_classes.mat'}, ...
                'Select GLASS-AI classification file(s)',app.LASTDIR,...
                "MultiSelect","on");
            if ~isequal(app.selectedGLASSAIFileList, 0)
                app.LASTDIR = app.GLASSAIAnnotationFilePath;
                if isequal(class(app.selectedGLASSAIFileList), 'char')
                    app.selectedGLASSAIFileList = {app.selectedGLASSAIFileList}; %change to cell
                    app.GLASSAIAnnotationFileTable.Data = app.selectedGLASSAIFileList;
                else
                    app.GLASSAIAnnotationFileTable.Data = app.selectedGLASSAIFileList';
                end

                % check if valid file pairings have been made to enable run
                % button
                if ~isempty(app.selectedCellAnnotationFileList)
                    app.validFilePairings = validatefilepairs(app);
                    if ~isempty(app.validFilePairings)
                        app.RunButton.Enable = true;
                        app.FilePairingLabel.Text = sprintf("%d paired files found",length(app.validFilePairings));
                        app.FilePairingLabel.FontColor = [0.25 0.7 0.3];
                    else
                        app.RunButton.Enable = false;
                        app.FilePairingLabel.Text = "No paired files found";
                        app.FilePairingLabel.FontColor = [0.9 0.1 0.3];
                    end
                end
            else
                return
            end
        end

        % Button pushed function: SelectManualAnnotationButton
        function SelectCellAnnotationButtonPushed(app, event)
            [app.selectedCellAnnotationFileList, app.CellAnnotationFilePath] = uigetfile({'*.csv'},'Select cell annotation file',app.LASTDIR, "MultiSelect","on");
            if ~isequal(app.selectedCellAnnotationFileList, 0)
                app.LASTDIR = app.CellAnnotationFilePath;
                if isequal(class(app.selectedCellAnnotationFileList), 'char')
                    app.selectedCellAnnotationFileList = {app.selectedCellAnnotationFileList}; %change to cell
                    app.ManualAnnotationFileTable.Data = app.selectedCellAnnotationFileList;
                else
                    app.ManualAnnotationFileTable.Data = app.selectedCellAnnotationFileList';
                end

                % update dropdown for picking variables from cell
                % annotation file(s)
                app.VariablePreviewDropDown.Items = app.selectedCellAnnotationFileList;
                app.VariablePreviewDropDown.Value = app.VariablePreviewDropDown.Items(1);

                % check if valid file pairings and variable assignments have been made to enable run
                % button
                if ~isempty(app.selectedGLASSAIFileList)
                    app.validFilePairings = validatefilepairs(app);

                    if ~isempty(app.validFilePairings)
                        if(app.fileVariablesAssigned)
                            app.RunButton.Enable = true;
                            app.FilePairingLabel.Text = sprintf("%d paired files found",length(app.validFilePairings));
                            app.FilePairingLabel.FontColor = [0.25 0.7 0.3];
                        else
                            app.FilePairingLabel.Text = sprintf("%d paired files found",length(app.validFilePairings),"\n Awaiting variable assignment.");
                            app.FilePairingLabel.FontColor = [0.8 0.8 0.3];
                        end
                    else
                        app.RunButton.Enable = false;
                        app.FilePairingLabel.Text = "No paired files found";
                        app.FilePairingLabel.FontColor = [0.9 0.1 0.3];
                    end
                end
            else
                return
            end
        end

        % Button pushed function: SelectWholeImageSummaryButton
        function SelectWholeImageSummaryButtonPushed(app, event)
            app.WholeImageInfoFilePath = uigetdir(app.LASTDIR,'Select whole image summary file destination directory');
            % store selected path and update label with file name
            if ~isequal(app.WholeImageInfoFilePath, 0)
                app.WholeImageSummaryLabel.Value = fullfile(app.WholeImageInfoFilePath,app.WholeImageInfoFileName);
                app.LASTDIR = app.WholeImageInfoFilePath;
            else
                return
            end
        end

        % Button pushed function: SelectGLASSAILogFileButton
        function SelectGLASSAILogFileButtonPushed(app, event)
            [app.GLASSAILogFileName, app.GLASSAILogFilePath] = uigetfile({'GLASS-AI_log*.txt'},'Select GLASS-AI log file',app.LASTDIR);
            % store selected path and update label with file name
            if ~isequal(app.GLASSAILogFileName, 0)
                app.GLASSAILogFileLabel.Value = app.GLASSAILogFileName;
                app.LASTDIR = app.GLASSAILogFilePath;
            else
                return
            end
        end

        % Value changed function: ImageNameDropDown
        function ImageNameDropDownValueChanged(app, event)
            value = app.ImageNameDropDown.Value;
            regexResult = extract(value,regexpPatter(app.ImageNameRegexEditField.Value));
            app.RegexResultEditField.Value = regexResult;
        end

        % Value changed function: VariablePreviewDropDown
        function VariablePreviewDropDownValueChanged(app, event)
            value = app.VariablePreviewDropDown.Value;
            opts = detectImportOptions(fullfile(app.CellAnnotationFilePath,value));
            inputColumnNames = opts.VariableNames;
            updateCellAnnotationVariableNames(inputColumnNames);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectManualAnnotationButton = uibutton(app.UIFigure, 'push');
            app.SelectManualAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCellAnnotationButtonPushed, true);
            app.SelectManualAnnotationButton.Tooltip = {'Select the directory that contains your images for analysis. All SVS and TIFF images in the selected directory will be displayed in File Name Table below for selection.'};
            app.SelectManualAnnotationButton.Text = 'Select cell annotation(s)';
            app.SelectGLASSAIAnnotationButton = uibutton(app.UIFigure, 'push');
            app.SelectGLASSAIAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGLASSAIAnnotationButtonPushed, true);
            app.SelectGLASSAIAnnotationButton.Tooltip = {'Select the directory that contains your images for analysis. All SVS and TIFF images in the selected directory will be displayed in File Name Table below for selection.'};
            app.SelectGLASSAIAnnotationButton.Text = 'Select GLASS-AI annotation(s)';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ManualAnnotationFileTable = uitable(app.UIFigure);
            app.ManualAnnotationFileTable.ColumnName = {'Cell Annotation Files'};
            app.ManualAnnotationFileTable.RowName = {};
            app.GLASSAIAnnotationFileTable = uitable(app.UIFigure);
            app.GLASSAIAnnotationFileTable.ColumnName = {'GLASS-AI Annotation Files'};
            app.GLASSAIAnnotationFileTable.RowName = {};
            app.SelectWholeImageSummaryButton = uibutton(app.UIFigure, 'push');
            app.SelectWholeImageSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWholeImageSummaryButtonPushed, true);
            app.SelectWholeImageSummaryButton.Text = 'Select image summary output folder';
            app.WholeImageSummaryLabel = uitextarea(app.UIFigure);
            app.WholeImageSummaryLabel.Editable = 'off';
            app.WholeImageSummaryLabel.Placeholder = 'optional (@ GLASS-AI annot.)';
            app.SelectGLASSAILogFileButton = uibutton(app.UIFigure, 'push');
            app.SelectGLASSAILogFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGLASSAILogFileButtonPushed, true);
            app.SelectGLASSAILogFileButton.Text = 'Select GLASS-AI logfile';
            app.GLASSAILogFileLabel = uitextarea(app.UIFigure);
            app.GLASSAILogFileLabel.Editable = 'off';
            app.GLASSAILogFileLabel.Placeholder = 'optional (use default parameters)';
            app.FilePairingLabel = uilabel(app.UIFigure);
            app.FilePairingLabel.Text = '';
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'Stained GLASS-AI v#.#';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellAnnotationFileVariablesTab = uitab(app.TabGroup);
            app.CellAnnotationFileVariablesTab.Title = 'Cell Annotation File Variables';
            app.ImagenameDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.ImagenameDropDownLabel.Text = 'Image name';
            app.ImageNameDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.ImageNameDropDown.Items = {};
            app.ImageNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageNameDropDownValueChanged, true);
            app.ImageNameDropDown.Placeholder = 'Select column name';
            app.ImageNameDropDown.Value = {};
            app.ImagenameextractorregexLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.ImagenameextractorregexLabel.Text = {'Image name '; 'extractor regex'};
            app.ImageNameRegexEditField = uieditfield(app.CellAnnotationFileVariablesTab, 'text');
            app.ImageNameRegexEditField.Value = '(?<=\.)([a-zA-Z0-9]*)(?=_)';
            app.StainclassDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.StainclassDropDownLabel.Text = 'Stain class';
            app.StainClassDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.StainClassDropDown.Items = {};
            app.StainClassDropDown.Placeholder = 'Select column name';
            app.StainClassDropDown.Value = {};
            app.StainIntensityDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.StainIntensityDropDownLabel.Text = 'Stain Intensity';
            app.StainIntensityDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.StainIntensityDropDown.Items = {};
            app.StainIntensityDropDown.Placeholder = 'Select column name';
            app.StainIntensityDropDown.Value = {};
            app.XpositionDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.XpositionDropDownLabel.Text = 'X position';
            app.XPositionDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.XPositionDropDown.Items = {};
            app.XPositionDropDown.Placeholder = 'Select column name';
            app.XPositionDropDown.Value = {};
            app.YpositionDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.YpositionDropDownLabel.Text = 'Y position';
            app.YPositionDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.YPositionDropDown.Items = {};
            app.YPositionDropDown.Placeholder = 'Select column name';
            app.YPositionDropDown.Value = {};
            app.PositionunitDropDownLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.PositionunitDropDownLabel.Text = 'Position unit';
            app.PositionUnitDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.PositionUnitDropDown.Items = {'millimeter', 'micrometer', 'inch', 'pixel', ''};
            app.PositionUnitDropDown.Value = 'millimeter';
            app.PreviewfileDropDown_2Label = uilabel(app.CellAnnotationFileVariablesTab);
            app.PreviewfileDropDown_2Label.Text = 'Preview file';
            app.VariablePreviewDropDown = uidropdown(app.CellAnnotationFileVariablesTab);
            app.VariablePreviewDropDown.Items = {};
            app.VariablePreviewDropDown.ValueChangedFcn = createCallbackFcn(app, @VariablePreviewDropDownValueChanged, true);
            app.VariablePreviewDropDown.Placeholder = 'Select file';
            app.VariablePreviewDropDown.Value = {};
            app.ResultEditFieldLabel = uilabel(app.CellAnnotationFileVariablesTab);
            app.ResultEditFieldLabel.Enable = 'off';
            app.ResultEditFieldLabel.Text = 'Result';
            app.RegexResultEditField = uieditfield(app.CellAnnotationFileVariablesTab, 'text');
            app.RegexResultEditField.Editable = 'off';
            app.RegexResultEditField.Enable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            xlabel(app.UIAxes, 'Stain intensity')
            ylabel(app.UIAxes, 'Cumulative Frequency')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [0 1];
            app.UIAxes.YTickLabel = {'0'; '100'};
            app.PreviewfileDropDownLabel = uilabel(app.Tab2);
            app.PreviewfileDropDownLabel.Text = 'Preview file';
            app.PreviewfileDropDown = uidropdown(app.Tab2);
            app.PreviewfileDropDown.Items = {};
            app.PreviewfileDropDown.Placeholder = 'Cell Annotation File';
            app.PreviewfileDropDown.Value = {};
            app.StainthresholdsEditFieldLabel = uilabel(app.Tab2);
            app.StainthresholdsEditFieldLabel.Text = 'Stain thresholds';
            app.StainthresholdsEditField = uieditfield(app.Tab2, 'text');
            app.StainthresholdsEditField.Placeholder = 'Input value(s)';
            app.ClassnamesTextAreaLabel = uilabel(app.Tab2);
            app.ClassnamesTextAreaLabel.Text = 'Class names';
            app.ClassnamesTextArea = uitextarea(app.Tab2);
            app.ClassnamesTextArea.Placeholder = 'Positive';
            app.ClassnamesTextArea.Value = {'Negative'; 'Positive'};
            app.RefreshplotButton = uibutton(app.Tab2, 'push');
            app.RefreshplotButton.Text = 'Refresh plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stained_GLASS_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end